classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RightPanel               matlab.ui.container.Panel
        CurrentWindSpeedLabel    matlab.ui.control.Label
        WindSpeedAxesUI          matlab.ui.control.UIAxes
        CenterPanel              matlab.ui.container.Panel
        SelectCityDropDown       matlab.ui.control.DropDown
        SelectCityLabel          matlab.ui.control.Label
        GetInformationButton     matlab.ui.control.Button
        CurrentHumidityLabel     matlab.ui.control.Label
        HumidityAxesUI           matlab.ui.control.UIAxes
        LeftPanel                matlab.ui.container.Panel
        CurrentTemperatureLabel  matlab.ui.control.Label
        AddCityButton            matlab.ui.control.Button
        CityEditField            matlab.ui.control.EditField
        TemperatureAxesUI        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function forecastData = fetchFiveDayForecast(app, cityName)
            apiKey = '023a7640a0b0fb3b619c06ab9af9ee66';
        
            % Convert string to character array if necessary
            if isstring(cityName)
                cityName = char(cityName);
            end
        
            % Create the API URL
            apiUrl = ['http://api.openweathermap.org/data/2.5/forecast?q=', cityName, '&appid=', apiKey];
            response = NaN;

            % Use try-catch to see city name is valid or not
            try
                % Send the HTTP GET request
                response = webread(apiUrl);
            catch
                warning = sprintf('There is no city named "%s"', cityName);
                uialert(app.UIFigure, warning, 'City Input Error');
            end
        
            % Since the response is already a JSON structure, assign it directly
            forecastData = response;
        end

        
        function updateGraphs(app, forecastData)
            % Extract temperature, humidity, and wind speed data
            temperatures = extractTemperatures(app, forecastData);
            humidity = extractHumidity(app, forecastData);
            windSpeed = extractWindSpeed(app, forecastData);
            
            % Update the Temperature graph
            plot(app.TemperatureAxesUI, temperatures.dates, temperatures.values, '-o');
            xlabel(app.TemperatureAxesUI, 'Date');
            ylabel(app.TemperatureAxesUI, 'Temperature (°C)');
            
            % Update the Humidity graph
            plot(app.HumidityAxesUI, humidity.dates, humidity.values, '-x');
            xlabel(app.HumidityAxesUI, 'Date');
            ylabel(app.HumidityAxesUI, 'Humidity (%)');
            
            % Update the Wind Speed graph
            plot(app.WindSpeedAxesUI, windSpeed.dates, windSpeed.values, '-*');
            xlabel(app.WindSpeedAxesUI, 'Date');
            ylabel(app.WindSpeedAxesUI, 'Wind Speed (m/s)');
        end
        
        % Helper function to extract temperature data
        function temperatures = extractTemperatures(app, forecastData)
            % Initialize arrays to hold the dates and temperature values
            dates = datetime([], 'ConvertFrom', 'posixtime');
            values = [];
            
            % Loop through the forecast data to extract temperatures
            for i = 1:numel(forecastData.list)
                % Use try-catch to avoid brace errors
                try
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list{i}.dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the temperature value in Kelvin
                    tempValueKelvin = forecastData.list{i}.main.temp;
                    
                    % Convert the temperature from Kelvin to Celsius
                    tempValueCelsius = tempValueKelvin - 273.15;
                catch
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list(i).dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the temperature value in Kelvin
                    tempValueKelvin = forecastData.list(i).main.temp;
                    
                    % Convert the temperature from Kelvin to Celsius
                    tempValueCelsius = tempValueKelvin - 273.15;
                end
                values(end+1) = tempValueCelsius;
            end
            
            % Return a structure with the dates and values
            temperatures.dates = dates;
            temperatures.values = values;
        end

        % Helper function to extract humidity data
        function humidity = extractHumidity(app, forecastData)
            % Initialize arrays to hold the dates and humidity values
            dates = datetime([], 'ConvertFrom', 'posixtime');
            values = [];
            
            % Loop through the forecast data to extract humidity
            for i = 1:numel(forecastData.list)
                % Use try-catch to avoid brace errors
                try
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list{i}.dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the humidity value
                    humidityValue = forecastData.list{i}.main.humidity;
                catch
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list(i).dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the humidity value
                    humidityValue = forecastData.list(i).main.humidity;
                end
                values(end+1) = humidityValue;
            end
            
            % Return a structure with the dates and values
            humidity.dates = dates;
            humidity.values = values;
        end
        
        % Helper function to extract wind speed data
        function windSpeed = extractWindSpeed(app, forecastData)
            % Initialize arrays to hold the dates and wind speed values
            dates = datetime([], 'ConvertFrom', 'posixtime');
            values = [];
            
            % Loop through the forecast data to extract wind speeds
            for i = 1:numel(forecastData.list)
                % Use try-catch to avoid brace errors
                try
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list{i}.dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the wind speed value
                    windSpeedValue = forecastData.list{i}.wind.speed;
                catch
                    % Convert the date from Unix time to datetime
                    date = datetime(forecastData.list(i).dt, 'ConvertFrom', 'posixtime');
                    dates(end+1) = date;
                    
                    % Extract the wind speed value
                    windSpeedValue = forecastData.list(i).wind.speed;
                end
                values(end+1) = windSpeedValue;
            end
            
            % Return a structure with the dates and values
            windSpeed.dates = dates;
            windSpeed.values = values;
        end

        % Function to display current conditions
        function displayCurrentConditions(app, forecastData)
            % Use try-catch to avoid brace errors
            try
                % Extract the current conditions from the first entry of the forecast data
                currentConditions = forecastData.list{1}.main;
                currentWind = forecastData.list{1}.wind;
            catch
                % Extract the current conditions from the first entry of the forecast data
                currentConditions = forecastData.list(1).main;
                currentWind = forecastData.list(1).wind;
            end
            
            % Substract 273.15 to convert kelvin to celsius 
            currentTemperature = currentConditions.temp - 273.15;
            currentHumidity = currentConditions.humidity;
            currentWindSpeed = currentWind.speed;
            
            % Update the labels to display the current conditions
            app.CurrentTemperatureLabel.Text = sprintf('Temperature: %.1f°C', currentTemperature);
            app.CurrentHumidityLabel.Text = sprintf('Humidity: %d%%', currentHumidity);
            app.CurrentWindSpeedLabel.Text = sprintf('Wind Speed: %.2f m/s', currentWindSpeed);
            
            % Update Graph titles
            city = app.SelectCityDropDown.Value;
            app.TemperatureAxesUI.Title.String = sprintf('Temperature Forecast for %s', city);
            app.HumidityAxesUI.Title.String = sprintf('Humudity Forecast for %s', city);
            app.WindSpeedAxesUI.Title.String = sprintf('Wind Speed Forecast for %s', city);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddCityButton
        function onAddCityButtonPressed(app, event)
            % Get the new city name from the Edit Field
            newCity = app.CityEditField.Value;
            
            % Check if the new city is not empty and not already in the dropdown
            if ~isempty(newCity) && ~any(strcmp(app.SelectCityDropDown.Items, newCity))

                % Check if the city name is valid
                forecastData = fetchFiveDayForecast(app, newCity);
                if ~isempty(forecastData) && isfield(forecastData, 'list') && isfield(forecastData, 'city')
                    % Add the new city to the dropdown list
                    app.SelectCityDropDown.Items{end+1} = newCity;
                end
            else
                % Display an error message if the city is empty or already exists
                uialert(app.UIFigure, 'Please enter a valid city name that is not already in the list.', 'City Input Error');
            end
        end

        % Button pushed function: GetInformationButton
        function onGetInformationButtonPressed(app, event)
            % Select the new city in the dropdown
            city = app.SelectCityDropDown.Value;
            if ~isempty(city)
                % Fetch and display the forecast data for the new city
                forecastData = fetchFiveDayForecast(app, city);
                updateGraphs(app, forecastData);
                displayCurrentConditions(app, forecastData);
            else
                uialert(app.UIFigure, 'Selected city can not be blank!', 'Selected City Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.TemperatureAxesUI = uiaxes(app.LeftPanel);
            title(app.TemperatureAxesUI, 'Temperature')
            xlabel(app.TemperatureAxesUI, 'Date')
            ylabel(app.TemperatureAxesUI, 'Temperature (°C)')
            zlabel(app.TemperatureAxesUI, 'Z')
            app.TemperatureAxesUI.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CityEditField = uieditfield(app.LeftPanel, 'text');
            app.AddCityButton = uibutton(app.LeftPanel, 'push');
            app.AddCityButton.ButtonPushedFcn = createCallbackFcn(app, @onAddCityButtonPressed, true);
            app.AddCityButton.Text = 'Add City';
            app.CurrentTemperatureLabel = uilabel(app.LeftPanel);
            app.CurrentTemperatureLabel.Text = 'Temperature: %.1f °C';
            app.CenterPanel = uipanel(app.UIFigure);
            app.HumidityAxesUI = uiaxes(app.CenterPanel);
            title(app.HumidityAxesUI, 'Humudity Forecest')
            xlabel(app.HumidityAxesUI, 'Date')
            ylabel(app.HumidityAxesUI, 'Humudity (%)')
            zlabel(app.HumidityAxesUI, 'Z')
            app.HumidityAxesUI.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CurrentHumidityLabel = uilabel(app.CenterPanel);
            app.CurrentHumidityLabel.Text = 'Humidity: %d%%';
            app.GetInformationButton = uibutton(app.CenterPanel, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @onGetInformationButtonPressed, true);
            app.GetInformationButton.Text = 'Get Information';
            app.SelectCityLabel = uilabel(app.CenterPanel);
            app.SelectCityLabel.Text = 'Select City:';
            app.SelectCityDropDown = uidropdown(app.CenterPanel);
            app.SelectCityDropDown.Items = {};
            app.SelectCityDropDown.Value = {};
            app.RightPanel = uipanel(app.UIFigure);
            app.WindSpeedAxesUI = uiaxes(app.RightPanel);
            title(app.WindSpeedAxesUI, 'Wind Speed Foracest')
            xlabel(app.WindSpeedAxesUI, 'Date')
            ylabel(app.WindSpeedAxesUI, 'Wind Speed (m/s)')
            zlabel(app.WindSpeedAxesUI, 'Z')
            app.WindSpeedAxesUI.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CurrentWindSpeedLabel = uilabel(app.RightPanel);
            app.CurrentWindSpeedLabel.Text = 'Wind Speed: %.2f m/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end